classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        cWheel_toolUIFigure       matlab.ui.Figure
        Button                    matlab.ui.control.Button
        PV_CurveEditField         matlab.ui.control.EditField
        PV_CurveEditFieldLabel    matlab.ui.control.Label
        c_pvEditField             matlab.ui.control.NumericEditField
        c_pvEditFieldLabel        matlab.ui.control.Label
        SctravelEditField         matlab.ui.control.EditField
        SctravelEditFieldLabel    matlab.ui.control.Label
        PressureEditField         matlab.ui.control.EditField
        PressureEditFieldLabel    matlab.ui.control.Label
        DropDown                  matlab.ui.control.DropDown
        Label                     matlab.ui.control.Label
        MATLAB2021b20241009Label  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %% 拟合公式% 


P=str2num(app.PressureEditField.Value);%采集压力信号bar

sctravel=str2num(app.SctravelEditField.Value);% 伺服缸位移行程 sctravel   mm
 V=sctravel*(387.6/1000); %需液量
 
% V=[0.259765	0.7207	1.060546	1.5898	1.960937	2.4	2.6768	3	3.1	3.2	3.3 3.4 3.5
%  ];

%选择前后轴 'FA / RA / FA_RA'
xlab='FA';


%% 后续内容为计算过程 勿动！
%PV_Curve x轴参数
xlab_FA=[1,2,3,5,8,12,20,30,45,55,70,85,100,130];
xlab_RA=[0.64,1.875,9.828,22.6,33.422,52.53,74.4,85.062,102.7,142,143,144,145,146];
xlab_FA_RA=[1.25 2.5 5 10 20 50 100 150];


[xData, yData] = prepareCurveData( P, V );

    
% Set up fittype and options.
ft = fittype( app.DropDown.Value, 'independent', 'x', 'dependent', 'y' );
opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
opts.Display = 'Off';

switch (app.DropDown.Value)
    case app.DropDown.Items(1)
opts.StartPoint = [0.654079098476782 0.689214503140008 0.748151592823709];
case app.DropDown.Items(2)
    opts.StartPoint = [0.654079098476782 0.689214503140008 0.748151592823709 0.7];
    case app.DropDown.Items(3)
opts.StartPoint = [0.654079098476782 0.689214503140008 0.748151592823709 0.7 0.1 0.1 0.1];
case app.DropDown.Items(4)
    opts.StartPoint = [0.654079098476782 0.689214503140008 0.748151592823709 0.7 0.1 0.1];
    case app.DropDown.Items(5)
         opts.StartPoint = [1 2 3 4 5 6];
end

% Fit model to data.
a= fit( xData, yData, ft, opts );

syms  x  
switch (app.DropDown.Value)
    case app.DropDown.Items(1)
y=a.A*x^0.25 + a.B*x^0.5 +a.C*x;
case app.DropDown.Items(2)
   y=a.A*x^0.25 + a.B*x^0.5 +a.C*x+a.D;
     case app.DropDown.Items(3)
y=a.A*(a.B*x+a.C)^(a.D*x+a.E)+a.F*x+a.G
case app.DropDown.Items(4)
   y=a.A*(a.B*x+a.C)^(a.D*x+a.E)+a.F*x
    case app.DropDown.Items(5) 
    y=a.F+x*(a.E+x*(a.D+x*(a.C+x*(a.B+x*(a.A)))));
end


dz=diff(y,x);
yins=symfun(y,x);%算得PV曲线函数
fins=symfun(dz,x);%算得导数函数

if xlab=='FA'
    axle=xlab_FA;
elseif xlab=='RA'
axle=xlab_RA;

% else xlab=='FA_RA' %数组个数不统一 注释
% axle=xlab_FA_RA;
end
dy=eval (yins(axle));%根据PV曲线函数 取得不同压力下对应的 需液量
da=eval (fins(axle));%根据导数函数    取得不同压力下对应的 斜率值 

ka=eval (fins(100));%算出 100bar下对应的   斜率值    即a值         y=ax+b
kv=eval(yins(100)); %算出100bar下对应的   需液量     即y值         y=ax+b 
Elas100=ka*100 ;    %算出100bar下对应的   Elas100值  即y-b的值(ax) y=ax+b 
 class(dy./ eval(yins(100)));
app.c_pvEditField.Value=100/Elas100;
app.PV_CurveEditField.Value=[ '[',num2str( ka./ da), ']'];%将100bar做为基准 算出value数组

h = plot( app.UIAxes, xData, yData ,'-.*',axle,dy);
legend( h, 'V vs. P', '输出曲线', 'Location', 'southeast', 'Interpreter', 'none' );

xlabel( app.UIAxes, 'P', 'Interpreter', 'none' );
ylabel(app.UIAxes, 'V', 'Interpreter', 'none' );


        end

        % Value changed function: c_pvEditField
        function c_pvEditFieldValueChanged(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.cWheel_toolUIFigure = uifigure('Visible', 'off');
            app.cWheel_toolUIFigure.Name = 'cWheel_tool';
            app.UIAxes = uiaxes(app.cWheel_toolUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MATLAB2021b20241009Label = uilabel(app.cWheel_toolUIFigure);
            app.MATLAB2021b20241009Label.Text = {'赵荣康  '; 'MATLAB 2021b '; '2024-10-09'};
            app.Label = uilabel(app.cWheel_toolUIFigure);
            app.Label.Text = '拟合公式';
            app.DropDown = uidropdown(app.cWheel_toolUIFigure);
            app.DropDown.Items = {'A*x^0.25 +B*x^0.5 +C*x', 'A*x^0.25 +B*x^0.5 +C*x+D', 'A*(B*x+C)^(D*x+E)+F*x+G', 'A*(B*x+C)^(D*x+E)+F*x', 'A*x^5+B*x^4+C*x^3+D*x^2+E*x^1+F '};
            app.DropDown.Value = 'A*x^0.25 +B*x^0.5 +C*x+D';
            app.PressureEditFieldLabel = uilabel(app.cWheel_toolUIFigure);
            app.PressureEditFieldLabel.Text = 'Pressure';
            app.PressureEditField = uieditfield(app.cWheel_toolUIFigure, 'text');
            app.PressureEditField.Value = '[1	5.9688	10.406	18	23.969	31	39.656	48.531	57.094	66.344	75.969	86	93.938	104.91	113.78	125.56	132.53 ]';
            app.SctravelEditFieldLabel = uilabel(app.cWheel_toolUIFigure);
            app.SctravelEditFieldLabel.Text = 'Sctravel';
            app.SctravelEditField = uieditfield(app.cWheel_toolUIFigure, 'text');
            app.SctravelEditField.Value = '[1.6094	2.043	2.5039	3.0586	3.457	3.957	4.543	5.0781	5.5547	6.0469	6.5234	7.043	7.4883	8.0195	8.4922	9.1328	9.4219 ]';
            app.c_pvEditFieldLabel = uilabel(app.cWheel_toolUIFigure);
            app.c_pvEditFieldLabel.Text = 'c_pv';
            app.c_pvEditField = uieditfield(app.cWheel_toolUIFigure, 'numeric');
            app.c_pvEditField.ValueChangedFcn = createCallbackFcn(app, @c_pvEditFieldValueChanged, true);
            app.c_pvEditField.Value = 1.5;
            app.PV_CurveEditFieldLabel = uilabel(app.cWheel_toolUIFigure);
            app.PV_CurveEditFieldLabel.Text = 'PV_Curve';
            app.PV_CurveEditField = uieditfield(app.cWheel_toolUIFigure, 'text');
            app.Button = uibutton(app.cWheel_toolUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '运行';
            % Show the figure after all components are created
            app.cWheel_toolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.cWheel_toolUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.cWheel_toolUIFigure)
        end
    end
end